classdef DEMO_spectL_F0DL_3AFC_Adaptive < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        start     matlab.ui.control.Button
        w3        matlab.ui.control.Button
        w2        matlab.ui.control.Button
        w1        matlab.ui.control.Button
        info      matlab.ui.control.Label
    end


    properties (Access = private)
        trial % trial number
        currtarget % The correct answer
        response % The answer picked by the subject
        currsignal % The audio to be played for the trial
        responsetable % Keeps full list of responses
        totaltrials % Maximum number of trials if no convergence
        trialaudiopath
        initstep = 2 %since in this version, we start so high, we will allow 3 step adjustment until first two reversals. Then assume they are in the right zone
        largestep = 2 % The parameter change will be larger initially
        smallstep = 1 % The parameter change will be smaller later
        maxval = 20 % All parameters will live on a 50 point grid
        minval = 1 % lowest value is designated as 1
        % The parameter value for current trial (set to starting value)
        currparam
        previousparam
        fs
        rank
        difLimen
        direction
        ear
        feedbackdur
        previouscorrect = false; % Keeps track for 2-down-one-up
        currreversals = 0;
        nreversals_stepchange1 = 2;
        nreversals_stepchange2 = 4;
        nreversals_converge = 12;
        nfiles_per_param = 6;
        currwords = {'FIRST', 'SECOND', 'THIRD'}

        % For TDT if using
        f1RZ % ActiveX figure for TDT soundcard
        RZ  % Soundcard object
        level % Sound level of target sound
        useTDT  % Whether or not to use the TDT

        % For maanaging individual data
        subj % Current subject ID
        respDir % Where to save response results
    end

    methods (Access = private)

        function dat = loadcurrtrial(app)
            % Choose one of N files for a given param level
            filenum = randi(app.nfiles_per_param, 1);

            fname = fullfile(app.trialaudiopath,...
                strcat('trial', num2str(app.currparam), '_', ...
                num2str(app.rank), '_', num2str(filenum), '.mat'));
            dat = load(fname);
        end

        function nrevs = countreversals(app)
            % Counts reversal for 2-down-1-up
            paramlist = app.responsetable(:, 4);
            revList = [];
            downList = [];
            upList = [];
            nReversals = 0;
            for k = 4:numel(paramlist)
                if((paramlist(k-1) > paramlist(k)) ...
                        && (paramlist(k-1) == paramlist(k-2)) ...
                        && (paramlist(k-2) > paramlist(k-3)))
                    nReversals = nReversals + 1;
                    revList = [revList, (k-1)]; %#ok<*AGROW>
                    downList = [downList, (k-1)];
                end
            end

            for k = 3:numel(paramlist)
                if((paramlist(k-1) < paramlist(k)) ...
                        && (paramlist(k-1) < paramlist(k-2)))
                    nReversals = nReversals + 1;
                    revList = [revList, (k-1)];
                    upList = [upList, (k-1)];
                end
            end

            for k = 4:numel(paramlist)
                if((paramlist(k-1) < paramlist(k)) ...
                        && (paramlist(k-1) == paramlist(k-2)) ...
                        && (paramlist(k-2) < paramlist(k-3)))
                    nReversals = nReversals + 1;
                    revList = [revList, (k-1)];
                    upList = [upList, (k-1)];
                end
            end
            revList = sort(revList);

            nrevs = numel(revList);

        end

        function setupNextTrial(app)
            fprintf(1, 'Trial #%d, Param = %d\n', app.trial, app.currparam);
            app.responsetable(app.trial, 1) = app.trial;
            app.responsetable(app.trial, 2) = app.currtarget;
            app.responsetable(app.trial, 3) = app.response;
            app.responsetable(app.trial, 4) = app.currparam;
            app.responsetable(app.trial, 5) = app.difLimen;
            app.responsetable(app.trial, 6) = app.direction;
            app.trial = app.trial + 1;
            app.currreversals = app.countreversals();


            if app.response == app.currtarget  % Correct answer
                fprintf(1, 'Correct :) \n');
                if ~app.previouscorrect % Previous incorrect
                    app.previouscorrect = true;
                    app.previousparam = app.currparam;
                else  % Previous correct
                    % Check if 2 right in a row at the same level
                    if app.previousparam == app.currparam 
                        if app.currreversals >= app.nreversals_stepchange2
                            app.currparam = app.currparam - app.smallstep;
                        elseif app.currreversals >= app.nreversals_stepchange1
                            app.currparam = app.currparam - app.largestep;
                        else
                            app.currparam = app.currparam - app.initstep;
                        end
                    else
                        app.previousparam = app.currparam;
                    end
                end
            else % Wrong answer
                fprintf(1, 'Wrong :( \n');
                if app.previouscorrect
                    app.previouscorrect = false;
                end
                if app.currreversals >= app.nreversals_stepchange2
                    app.previousparam = app.currparam;
                    app.currparam = app.currparam + app.smallstep;
                elseif app.currreversals >= app.nreversals_stepchange1
                    app.previousparam = app.currparam;
                    app.currparam = app.currparam + app.largestep;
                else
                    app.previousparam = app.currparam;
                    app.currparam = app.currparam + app.initstep;                    
                end
            end
            if app.currparam > app.maxval
                app.currparam = app.maxval;
            end

            if app.currparam < app.minval
                app.currparam = app.minval;
            end


            if  app.trial == app.totaltrials
                % End credits
                app.endCredits();
            else
                dat = app.loadcurrtrial();
                app.currtarget = dat.answer;
                app.currparam = dat.param;
                app.currsignal = dat.y;
                app.difLimen = dat.difLimen;
                app.direction = dat.direction;
                app.startButtonPushed();
            end
        end

        function blankbuttons(app)
            app.w1.Text = '';
            app.w2.Text = '';
            app.w3.Text = '';
            app.w1.BackgroundColor = [0.96, 0.96, 0.96];
            app.w2.BackgroundColor = [0.96, 0.96, 0.96];
            app.w3.BackgroundColor = [0.96, 0.96, 0.96];
            app.w1.Enable = 'off';
            app.w2.Enable = 'off';
            app.w3.Enable = 'off';
        end

        function endCredits(app)
            datetag = char(string(datetime('now')));
            datetag(strfind(datetag,' ')) = '_';
            datetag(strfind(datetag,':')) = '_';
            saveName = [app.respDir, '/', app.subj,'_Rank_' ,num2str(app.rank),'_', datetag, '.mat'];
            responseList = app.responsetable;
            save(saveName, 'responseList');
            infoText = {'Done with current block!', 'Thank you!'};
            app.info.Text = infoText;
            app.blankbuttons();
            pause(0.5);
            if app.useTDT
                close_play_circuit(app.f1RZ, app.RZ);
            end
            app.returnCorrect();
            app.UIFigure.CloseRequestFcn()
        end
        
        function markCorrect(app)
            choice = app.currtarget;
            switch choice
                case 1
                    app.w1.BackgroundColor = 'g';
                case 2
                    app.w2.BackgroundColor = 'g';
                case 3
                    app.w3.BackgroundColor = 'g';
            end
        end
        
        function returnCorrect(app)
            correct = sum(app.responsetable(:,2)==app.responsetable(:,3))./size(app.responsetable,1)*100;
            
            if(correct>80)
                fprintf('PASS. With %f percent accuracy',correct);
            else
                fprintf('FAIL. With %f percent accuracy',correct)
            end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Store full results in 4 columns
            % Column 1: Trial number
            % Column 2: Target
            % Column 3: Response
            % Column 4: Param value (between 1 and 40)
            app.responsetable = zeros(app.totaltrials, 5);


            app.totaltrials = 21;
            app.trialaudiopath = './DEMO_trialaudio/';
            app.fs = 48828;
            app.feedbackdur = 0.5;
            app.currparam = 20;
            app.level = 80;
            startdat = load('./currsubj.mat');
            app.subj = startdat.subj;
            app.respDir = startdat.respDir;
            app.useTDT = startdat.useTDT;
            app.rank = startdat.rank;
            app.ear = startdat.ear;
            
            if app.useTDT
                %% Setup sound card
                fig_num=99;
                USB_ch=1;
                IAC = -1;
                FS_tag = 3;
                [app.f1RZ,app.RZ,~]=load_play_circuit(FS_tag,fig_num,USB_ch,0,IAC);
            end

            % Start with first trial
            app.trial = 1;
            dat = app.loadcurrtrial();
            app.currtarget = dat.answer;
            app.currparam = dat.param;
            app.currsignal = dat.y;
            app.difLimen = dat.difLimen;
            app.direction = dat.direction;
        end

        % Button pushed function: start
        function startButtonPushed(app, event)

            switch app.ear
                case 1
                    app.currsignal = [app.currsignal; zeros(1,length(app.currsignal))];
                case 2 
                    app.currsignal = [zeros(1,length(app.currsignal)); app.currsignal];
                case 3 
                    app.currsignal = [app.currsignal; app.currsignal];
            end

            app.blankbuttons();


            if ~app.useTDT
                % When not using TDT
                % Level is not calibrated
                sound(app.currsignal', app.fs);  
            else
                soundTDT(app.currsignal', app.RZ, app.level);
            end

            dur = size(app.currsignal, 2)/app.fs;
            pause(dur)
            app.w1.Text = app.currwords{1};
            app.w2.Text = app.currwords{2};
            app.w3.Text = app.currwords{3};
            app.w1.Enable = 'on';
            app.w2.Enable = 'on';
            app.w3.Enable = 'on';
            app.start.Visible = 'off';
        end

        % Button pushed function: w1
        function w1ButtonPushed(app, event)
            app.response = 1;
            if app.response == app.currtarget
                app.w1.BackgroundColor = 'g';
            else
                app.w1.BackgroundColor = 'r';
                app.markCorrect();
            end
            pause(app.feedbackdur);
            if app.trial < app.totaltrials
                app.setupNextTrial;
            end
        end

        % Button pushed function: w2
        function w2ButtonPushed(app, event)
            app.response = 2;
            if app.response == app.currtarget
                app.w2.BackgroundColor = 'g';
            else
                app.w2.BackgroundColor = 'r';
                app.markCorrect();
            end
            pause(app.feedbackdur);
            if app.trial < app.totaltrials
                app.setupNextTrial;
            end
        end

        % Button pushed function: w3
        function w3ButtonPushed(app, event)
            app.response = 3;
            if app.response == app.currtarget
                app.w3.BackgroundColor = 'g';
            else
                app.w3.BackgroundColor = 'r';
                app.markCorrect();
            end
            pause(app.feedbackdur);
            if app.trial < app.totaltrials
                app.setupNextTrial;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.info = uilabel(app.UIFigure);
            app.info.Text = 'Which sound is different from the other two?';
            app.w1 = uibutton(app.UIFigure, 'push');
            app.w1.ButtonPushedFcn = createCallbackFcn(app, @w1ButtonPushed, true);
            app.w1.Enable = 'off';
            app.w1.Text = '';
            app.w2 = uibutton(app.UIFigure, 'push');
            app.w2.ButtonPushedFcn = createCallbackFcn(app, @w2ButtonPushed, true);
            app.w2.Enable = 'off';
            app.w2.Text = '';
            app.w3 = uibutton(app.UIFigure, 'push');
            app.w3.ButtonPushedFcn = createCallbackFcn(app, @w3ButtonPushed, true);
            app.w3.Enable = 'off';
            app.w3.Text = '';
            app.start = uibutton(app.UIFigure, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.start.Text = 'START';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DEMO_spectL_F0DL_3AFC_Adaptive

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end